classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Image                          matlab.ui.control.Image
        SourceButton                   matlab.ui.control.Button
        ProcessButton                  matlab.ui.control.Button
        ShowIntermediatesButton        matlab.ui.control.Button
        OpenResultsButton              matlab.ui.control.Button
        RampSizeDropDownLabel          matlab.ui.control.Label
        RampSizeDropDown               matlab.ui.control.DropDown
        OutputColumnsEditFieldLabel    matlab.ui.control.Label
        OutputColumnsEditField         matlab.ui.control.EditField
        ScaleEditFieldLabel            matlab.ui.control.Label
        ScaleEditField                 matlab.ui.control.EditField
        HistogramEqualizationCheckBox  matlab.ui.control.CheckBox
        UnSharpMaskingCheckBox         matlab.ui.control.CheckBox
        ReadyLabel                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        file_input % File path selected by user
        file_output % Output file after processing
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SourceButton
        function SourceButtonPushed(app, event)
            [file,path] = uigetfile('*');
            app.file_input = fullfile(path,file);
            try
            app.Image.ImageSource = imread(app.file_input);
            catch
                app.ReadyLabel.Text = "Read Failed.";
                return;
            end
            app.ReadyLabel.Text = "Ready";
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            app.ReadyLabel.Text = "Processing...";
            try
            ramp_size = app.RampSizeDropDown.Value;
            if(ramp_size == 'Small')
                do_large_ramp = false;
            else
                do_large_ramp = true;
            end
            catch
                app.ReadyLabel.Text = "Invalid Ramp.";
                return;
            end
            do_hist_eq = app.HistogramEqualizationCheckBox.Value;
            do_unsharp = app.UnSharpMaskingCheckBox.Value;
            [cols, tf] = str2num(app.OutputColumnsEditField.Value);
            if(~tf)
                app.ReadyLabel.Text = "Invalid Columns.";
                return;
            end
            if(cols > 1024)
                app.ReadyLabel.Text = "Columns exceeds NotePad Limit!";
                return;
            end
            [scale, tf] = str2num(app.ScaleEditField.Value);
            if(~tf)
                app.ReadyLabel.Text = "Invalid Scale.";
                return;
            end
            try
            im2ascii(app.file_input, cols, scale, do_large_ramp, do_hist_eq, do_unsharp);
            catch
                app.ReadyLabel.Text = "Error Processing!";
                return;
            end
            app.file_output = strcat([app.file_input,'.txt']);
            app.ReadyLabel.Text = "Done";
        end

        % Button pushed function: ShowIntermediatesButton
        function ShowIntermediatesButtonPushed(app, event)
            % Read Image and Comvert to Grayscale
            image = imread(app.file_input);
            image_gray = rgb2gray(image);
            
            % Do the preprocessing
            image_hist = histeq(image_gray);
            image_sharp = imsharpen(image_hist);
            
            % Plot all
            figure;subplot(2, 2, 1);imshow(image);title("1. Original");
            subplot(2, 2, 2);imshow(image_gray);title("2. Gray Scale");
            subplot(2, 2, 3);imshow(image_hist);title("3. Histogram Equalization");
            subplot(2, 2, 4);imshow(image_sharp);title("4. UnSharp Mask");
           
        end

        % Button pushed function: OpenResultsButton
        function OpenResultsButtonPushed(app, event)
            eval(['!notepad ' app.file_output])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.SourceButton = uibutton(app.GridLayout, 'push');
            app.SourceButton.ButtonPushedFcn = createCallbackFcn(app, @SourceButtonPushed, true);
            app.SourceButton.Text = 'Source...';
            app.ProcessButton = uibutton(app.GridLayout, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.ShowIntermediatesButton = uibutton(app.GridLayout, 'push');
            app.ShowIntermediatesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowIntermediatesButtonPushed, true);
            app.ShowIntermediatesButton.Text = 'Show Intermediates';
            app.OpenResultsButton = uibutton(app.GridLayout, 'push');
            app.OpenResultsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenResultsButtonPushed, true);
            app.OpenResultsButton.Text = 'Open Results';
            app.RampSizeDropDownLabel = uilabel(app.GridLayout);
            app.RampSizeDropDownLabel.Text = 'Ramp Size';
            app.RampSizeDropDown = uidropdown(app.GridLayout);
            app.RampSizeDropDown.Items = {'Small', 'Large'};
            app.RampSizeDropDown.Value = 'Small';
            app.OutputColumnsEditFieldLabel = uilabel(app.GridLayout);
            app.OutputColumnsEditFieldLabel.Text = 'Output Columns';
            app.OutputColumnsEditField = uieditfield(app.GridLayout, 'text');
            app.OutputColumnsEditField.Value = '512';
            app.ScaleEditFieldLabel = uilabel(app.GridLayout);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.GridLayout, 'text');
            app.ScaleEditField.Value = '0.55';
            app.HistogramEqualizationCheckBox = uicheckbox(app.GridLayout);
            app.HistogramEqualizationCheckBox.Text = 'Histogram Equalization';
            app.HistogramEqualizationCheckBox.Value = true;
            app.UnSharpMaskingCheckBox = uicheckbox(app.GridLayout);
            app.UnSharpMaskingCheckBox.Text = 'UnSharp Masking';
            app.UnSharpMaskingCheckBox.Value = true;
            app.ReadyLabel = uilabel(app.GridLayout);
            app.ReadyLabel.Text = 'Ready';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end